classdef qPCR_Analyzer_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        qPCRAnalyzerUIFigure       matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        NormalizeToDropDownLabel   matlab.ui.control.Label
        NormalizeToDropDown        matlab.ui.control.DropDown
        SelectFileButton           matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        ExportDataButton           matlab.ui.control.Button
        SortByLabel                matlab.ui.control.Label
        SortBySwitch               matlab.ui.control.Switch
        GroupBySwitchLabel         matlab.ui.control.Label
        GroupBySwitch              matlab.ui.control.Switch
        ExportFiguresButton        matlab.ui.control.Button
        IncludeNormalizerCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        tabledata % hold table for input data
        tableData_SampleSort %holds sample sorted tabledata
        tableData_TargetSort %holds target sorted tabledata
        chunked_data %processed data for algorithm
        normal_primer %primer that is to normalized to
        printData %Full data set with calculations in table format for export/visualization
        TabGroup
        Tab
        UIAxes
    end

    methods (Access = private)
    
        function [data] = readRawFile(app, filePath) %Reads file and trims it to appropriate dimesions
            raw = readtable(filePath, 'Sheet', 'Results', 'Range', 'D:K');
            data = raw(42:size(raw,1),[1,2,6,7,8]);
        end
        
        function TableData = Calculate(app, data) %takes the raw trimmed data, converts it to multidimensional table and performs CT Power and CT SD to it
            TableData = ChunkData(app, data);
            for i = 1:length(TableData)
                position = FindNormalPrimerPosition(app, TableData{i}); %find psoition of normalizing primers data (just need CT mean)
                if isUndetermined(app, TableData{i}{position,1})
                    Normal_CT = 40; %default for no CT
                    Normal_CT_SD = 0;
                else
                    Normal_CT = str2double(TableData{i}{position,1}{1,3});
                    Normal_CT_SD = str2double(TableData{i}{position,1}{1,4});
                end
                for j = 1:length(TableData{1,i}) %Calculate CT Power and CT SD for each sample and add to TableData
                    if isUndetermined(app, TableData{1,i}{j,1})
                        Sample_CT = 40; %default for no CT
                        Sample_CT_SD = 0;
                    else
                        Sample_CT = str2double(TableData{1,i}{j,1}{1,3});
                        Sample_CT_SD = str2double(TableData{1,i}{j,1}{1,4});
                    end
                    CT_Power = 2^(Normal_CT - Sample_CT);
                    TableData{1,i}{j,1} = [TableData{1,i}{j,1}, {CT_Power}];
                    if mod(j, 3) == 0 && j ~= 1 && isUndetermined(app, TableData{1,i}{j,1}) ~= 1
                        Sample_Data = cat(1, TableData{1,i}{j-2:j,1});
                        Sample_CT_Mean = mean([Sample_Data{:,6}]);
                        CT_SD = nanstd([Sample_Data{:,6}]);
                        TableData{1,i}{j-2,1} = [TableData{1,i}{j-2,1}, {Sample_CT_Mean},{CT_SD}];
                        TableData{1,i}{j-1,1} = [TableData{1,i}{j-1,1}, {Sample_CT_Mean},{CT_SD}];
                        TableData{1,i}{j,1} = [TableData{1,i}{j,1}, {Sample_CT_Mean},{CT_SD}];
                    elseif j == 1 || mod(j, 3) ~= 0
                        continue
                    else
                        Sample_CT_Mean = CT_Power;
                        CT_SD = 0;
                        TableData{1,i}{j-2,1} = [TableData{1,i}{j-2,1}, {Sample_CT_Mean},{CT_SD}];
                        TableData{1,i}{j-1,1} = [TableData{1,i}{j-1,1}, {Sample_CT_Mean},{CT_SD}];
                        TableData{1,i}{j,1} = [TableData{1,i}{j,1}, {Sample_CT_Mean},{CT_SD}];
                    end
                end
            end
        end
        
        function primerNames = PrimerNames(app, data) %finds and returns all names of primers
            primerNames = [data{2,2}]; %need to have a non empty array for ismember to function
            index = 2;
            for i = 2:size(data,1)
                check = ismember(char(data{i,2}), primerNames);
                if check == 0
                    primerNames(index) = data{i,2};
                    index = index + 1;
                end
            end
        end
        
        function primer = PredictNormalPrimer(app, primerNames) %checks the primer names against list of known normalizing primers, returns best guess
            normalizedPrimers = [{'HPRT'},{'hprt'},{'Gapdh'},{'B-Actin'},{'b-Actin'},{'b-actin'}];
            primer = primerNames(1);
            for element = 1:length(primerNames)
                for item = 1:length(normalizedPrimers)
                    if strcmp(primerNames(element),normalizedPrimers(item))
                        primer = primerNames(element);
                        break
                    end
                end
            end
        end
        
        
        function results = CleanDataNames(app, dataNames) %removes spaces from names so they can be used as variables (likely won't be used)
            for i=1:length(dataNames)
                dataNames(i) = {regexprep(char(dataNames{i}), ' ', '_')};
            end
            results = dataNames;
        end
        
        function results = ChunkData(app, data) %Returns a vector of matrices composed of the samples of the dataset, pass in the full data
            results = {}; %initializes results as cell vector
            data = data(2:size(data,1),:); %Removes the column labels
            temp_name = char(data(2,1)); %name of sample being tracked in loop
            temp_matrix = {}; %stores all replicates of sample during loop
            results_iterator = 1; %tracks where to store the temp_matrix into results
            temp_iterator = 1; %tracks where to store replicates in temp_matrix
            for i = 1:size(data,1)
                try
                    if char(data(i,1)) == temp_name
                        temp_matrix{temp_iterator,1} = data(i,:);
                        temp_iterator = temp_iterator + 1;
                    elseif temp_matrix == {}
                        continue
                    else
                        results(results_iterator) = temp_matrix;
                        temp_iterator = 2; %2 due to adding the failed case immediately to the next 'chunk'
                        temp_name = char(data{i,1});
                        temp_matrix{1,1} = data{i,:};
                        results_iterator = results_iterator + 1;
                    end
                catch
                    results{results_iterator} = temp_matrix;
                    temp_iterator = 2; %2 due to adding the failed case immediately to the next 'chunk'
                    temp_name = char(data{i,1});
                    temp_matrix = {};
                    temp_matrix{1,1} = data(i,:);
                    results_iterator = results_iterator + 1;
                end
            end
            for i = 1:length(results) %Following block finds all 'chunks' with common sample name and combines them, this can happen if the qPCR machine exceeds one row of samples (consider making seperate function
                for j = i+1:length(results)
                    try
                        if all(char(results{1,i}{1,1}{1,1}) == char(results{1, j}{1,1}{1,1}))
                            results{1, i} = [results{1,i}; results{1,j}];
                            results(j) = [];
                        end
                    catch
                        continue
                    end
                end
            end
        end
        
        function results = FindNormalPrimerPosition(app, chunk) %finds normal primer positions and returns it as a single value for use in calculate
            for i = 1:length(chunk) %just changed for debugging, revet if broken to i = length(chunk)
                if convertCharsToStrings(chunk{i,1}{1,2}) == convertCharsToStrings(app.normal_primer)
                    results = i;
                end
            end
        end
        
        function results = isUndetermined(app, sample) %checks to see if given cell array has undetermined CT values
            results = convertCharsToStrings(sample{1,3}) == "Undetermined";
        end
        
        function GeneratePlots(app)
            %Create Tabs
            app.TabGroup = uitabgroup(app.qPCRAnalyzerUIFigure);
            app.TabGroup.Position = [489,2,536,679];
            app.Tab = {};
            app.UIAxes = {};
            for i = 1:length(app.chunked_data)
                app.Tab = [app.Tab; uitab(app.TabGroup)];
                app.Tab(i).Title = app.chunked_data{1,i}{1,1}{1,1};
                app.UIAxes = [app.UIAxes; uiaxes(app.Tab(i))];
                title(app.UIAxes(i), [app.chunked_data{1,i}{1,1}{1,1}])
                ylabel(app.UIAxes(i), ['Fold/', app.normal_primer])
                app.UIAxes(i).Position = [2 1 477 652];
                tempData = cat(1,app.chunked_data{1,i}{:});
                tempNames = cellstr(cat(1, [tempData{1,2};tempData{4,2};tempData{7,2}]));
                tempValues = cat(1,[tempData{1,7};tempData{4,7};tempData{7,7}]);
                bar(app.UIAxes(i), categorical(tempNames), tempValues)
            end
        end
        
        function PrintData(app) %Takes app.chunked_data and converts it into a table for visualization and export
            for i = 1:length(app.chunked_data) %for all sample rows
                for j = 1:length(app.chunked_data{1,1}) %for all target rows
                    app.printData =  [app.printData; app.chunked_data{1,i}{j,1}];
                end
            end
        end
        
        function results = FindNumberReplicates(app) %only use after setting app.tableData_TargetSort
            results = 0;
            Name = string(app.tableData_TargetSort{1,2});
            while string(app.tableData_TargetSort{results+1,2}) == Name
                results = results + 1;
            end
        end
        
        
        function PlotData(app, DataString, PlotNormalizer) %plots data into bar graphs, app: app class, DataString: string "Target":app.tableDataTargetSort or "Sample":app.tableDataSampleSort, PlotNormalizer: dictates whether Normalizing sample gets plotted
            if DataString == "Target" || ~exist("DataString", "var")
                Data = app.tableData_TargetSort;
            elseif DataString == "Sample"
                Data = app.tableData_SampleSort;
            end
            if ~exist("PlotNormalizer", "var")
                PlotNormalizer = true;
            end
            if PlotNormalizer == true
                app.TabGroup = uitabgroup(app.qPCRAnalyzerUIFigure);
                app.TabGroup.Position = [489,2,536,679]; %489,2,536,679
                app.Tab = {};
                app.UIAxes = {};
                Name = string(Data{1,1}); %starting name for grouping
                gap = FindNumberReplicates(app);
                tempData = [];
                tempNames = [];
                index = 1; %Tracks index for graphics elements
                for i = 1:gap:length(Data) %Write function to determine # of replicates for '3' value temp
                    if Name == string(Data{i,1})
                        tempData = [tempData; Data{i,7}];
                        tempNames = [tempNames; string(Data{i,2})];
                    else %if no longer in current name, plot temps and reset with new name
                        Name = string(Data{i,1});
                        app.Tab = [app.Tab; uitab(app.TabGroup)];
                        app.Tab(index).Title = Name;
                        app.UIAxes = [app.UIAxes; uiaxes(app.Tab(index))];
                        title(app.UIAxes(index), [app.Tab(index).Title])
                        ylabel(app.UIAxes(index), ['Fold/', app.normal_primer])
                        app.UIAxes(index).Position = [2 1 477 652];
                        Names = cellstr(tempNames);
                        bar(app.UIAxes(index), categorical(Names), tempData)
                        tempData = [];
                        tempNames = [];
                        tempData = [tempData; Data{i,7}];
                        tempNames = [tempNames; string(Data{i,2})];
                        index = index + 1;
                    end
                end
                app.Tab = [app.Tab; uitab(app.TabGroup)];
                app.Tab(index).Title = Name;
                app.UIAxes = [app.UIAxes; uiaxes(app.Tab(index))];
                title(app.UIAxes(index), [app.Tab(index).Title])
                ylabel(app.UIAxes(index), ['Fold/', app.normal_primer])
                app.UIAxes(index).Position = [2 1 477 652];
                Names = cellstr(tempNames);
                bar(app.UIAxes(index), categorical(Names), tempData)
            else   %Plot without Normalizing target
                app.TabGroup = uitabgroup(app.qPCRAnalyzerUIFigure);
                app.TabGroup.Position = [489,2,536,679];
                app.Tab = {};
                app.UIAxes = {};
                Name = string(Data{1,1}); %starting name for grouping
                gap = FindNumberReplicates(app);
                tempData = [];
                tempNames = [];
                index = 1; %Tracks index for graphics elements
                for i = 1:gap:length(Data) %Write function to determine # of replicates for '3' value temp
                    if (DataString == "Sample" && string(Data{i,2}) == app.normal_primer) || (DataString == "Target" && string(Data{i,1}) == app.normal_primer)
                        continue
                    end
                    if Name == string(Data{i,1})
                        tempData = [tempData; Data{i,7}];
                        tempNames = [tempNames; string(Data{i,2})];
                    else %if no longer in current name, plot temps and reset with new name
                        app.Tab = [app.Tab; uitab(app.TabGroup)];
                        app.Tab(index).Title = Name;
                        Name = string(Data{i,1});
                        app.UIAxes = [app.UIAxes; uiaxes(app.Tab(index))];
                        title(app.UIAxes(index), [app.Tab(index).Title])
                        ylabel(app.UIAxes(index), ['Fold/', app.normal_primer])
                        app.UIAxes(index).Position = [2 1 477 652];
                        Names = cellstr(tempNames);
                        bar(app.UIAxes(index), categorical(Names), tempData)
                        tempData = [];
                        tempNames = [];
                        tempData = [tempData; Data{i,7}];
                        tempNames = [tempNames; string(Data{i,2})];
                        index = index + 1;
                    end
                end %Error: when filtering names you can't just i-gap for the name to plot
                app.Tab = [app.Tab; uitab(app.TabGroup)];
                app.Tab(index).Title = Name;
                app.UIAxes = [app.UIAxes; uiaxes(app.Tab(index))];
                title(app.UIAxes(index), [app.Tab(index).Title])
                ylabel(app.UIAxes(index), ['Fold/', app.normal_primer])
                app.UIAxes(index).Position = [2 1 477 652];
                Names = cellstr(tempNames);
                bar(app.UIAxes(index), categorical(Names), tempData)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = 'Open a raw qPCR "*.xlsx" file, the program will automatically generate figures based on best guess of the dataset. the data table in the top right will allow you to edit what sampes normalize to. hitting calculate will recreate the plots with your adjustments';
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.xlsx;*.xls'}, 'Excel Files', 'select file');
            [data] = readRawFile(app, fullfile(filePath, fileName));
            app.NormalizeToDropDown.Items = PrimerNames(app, data);
            app.normal_primer = PredictNormalPrimer(app, app.NormalizeToDropDown.Items);
            app.NormalizeToDropDown.Value = app.normal_primer;
            app.tabledata = data;
            app.chunked_data = Calculate(app, table2cell(data));
            PrintData(app);
            app.tableData_SampleSort = sortrows(app.printData, [1,2], {'descend', 'descend'}); %sorts by sample name then target name, stores to app variable
            app.tableData_TargetSort = sortrows([app.printData(:,2), app.printData(:,1), app.printData(:, 3:size(app.printData, 2))], [1,2], {'descend', 'descend'}); %flips sample name and target name of dataset, sorts by target name then sample name, stores to app variable
            app.UITable.Data = app.tableData_SampleSort(:,2:size(app.tableData_SampleSort,2));
            app.UITable.ColumnName = [data{1,2:size(data,2)}, 'CT Power', 'CT Power Mean', 'CT Power SD'];
            app.UITable.RowName = [app.tableData_SampleSort(:,1)];
            PlotData(app, "Sample", app.IncludeNormalizerCheckBox.Value)
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            [file,path] = uiputfile('*.xlsx');
            filename = fullfile(path,file);
            Table = table(app.UITable.Data);
            writetable(Table, filename);
        end

        % Value changed function: SortBySwitch
        function SortBySwitchValueChanged(app, event)
            value = app.SortBySwitch.Value;
            if value == 'Target'
                app.UITable.Data = app.tableData_TargetSort(:,2:size(app.tableData_TargetSort,2));
                app.UITable.ColumnName = ["Sample Name"; "CT Value"; "CT Mean"; "CT SD"; "CT Power"; "CT Power Mean"; "CT Power SD"];
                app.UITable.RowName = [app.tableData_TargetSort(:,1)];
            else
                app.UITable.Data = app.tableData_SampleSort(:,2:size(app.tableData_SampleSort,2));
                app.UITable.ColumnName = ["Target Name"; "CT Value"; "CT Mean"; "CT SD"; "CT Power"; "CT Power Mean"; "CT Power SD"];
                app.UITable.RowName = [app.tableData_SampleSort(:,1)];
            end
        end

        % Value changed function: GroupBySwitch
        function GroupBySwitchValueChanged(app, event)
            value = app.GroupBySwitch.Value;
            if value == "Sample"
%                 GeneratePlots(app)
                PlotData(app,"Sample", app.IncludeNormalizerCheckBox.Value)
            else
                PlotData(app,"Target", app.IncludeNormalizerCheckBox.Value)
            end
        end

        % Value changed function: IncludeNormalizerCheckBox
        function IncludeNormalizerCheckBoxValueChanged(app, event)
            value = app.IncludeNormalizerCheckBox.Value;
            PlotData(app, app.GroupBySwitch.Value, value)
        end

        % Value changed function: NormalizeToDropDown
        function NormalizeToDropDownValueChanged(app, event)
            value = app.NormalizeToDropDown.Value;
            data = app.tabledata;
            app.normal_primer = value;
            app.chunked_data = Calculate(app, table2cell(data));
            PrintData(app);
            app.tableData_SampleSort = sortrows(app.printData, [1,2], {'descend', 'descend'}); %sorts by sample name then target name, stores to app variable
            app.tableData_TargetSort = sortrows([app.printData(:,2), app.printData(:,1), app.printData(:, 3:size(app.printData, 2))], [1,2], {'descend', 'descend'}); %flips sample name and target name of dataset, sorts by target name then sample name, stores to app variable
            app.UITable.Data = app.tableData_SampleSort(:,2:size(app.tableData_SampleSort,2));
            app.UITable.ColumnName = [data{1,2:size(data,2)}, 'CT Power', 'CT Power Mean', 'CT Power SD'];
            app.UITable.RowName = [app.printData(:,1)];
            PlotData(app, app.GroupBySwitch.Value, app.IncludeNormalizerCheckBox.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.qPCRAnalyzerUIFigure = uifigure('Visible', 'off');
            app.qPCRAnalyzerUIFigure.Name = 'qPCR Analyzer';
            app.UITable = uitable(app.qPCRAnalyzerUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.NormalizeToDropDownLabel = uilabel(app.qPCRAnalyzerUIFigure);
            app.NormalizeToDropDownLabel.Text = 'Normalize To';
            app.NormalizeToDropDown = uidropdown(app.qPCRAnalyzerUIFigure);
            app.NormalizeToDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizeToDropDownValueChanged, true);
            app.SelectFileButton = uibutton(app.qPCRAnalyzerUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.TextArea = uitextarea(app.qPCRAnalyzerUIFigure);
            app.TextArea.Editable = 'off';
            app.ExportDataButton = uibutton(app.qPCRAnalyzerUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SortByLabel = uilabel(app.qPCRAnalyzerUIFigure);
            app.SortByLabel.Text = 'Sort By:';
            app.SortBySwitch = uiswitch(app.qPCRAnalyzerUIFigure, 'slider');
            app.SortBySwitch.Items = {'Sample', 'Target'};
            app.SortBySwitch.ValueChangedFcn = createCallbackFcn(app, @SortBySwitchValueChanged, true);
            app.SortBySwitch.Value = 'Sample';
            app.GroupBySwitchLabel = uilabel(app.qPCRAnalyzerUIFigure);
            app.GroupBySwitchLabel.Text = 'Group By:';
            app.GroupBySwitch = uiswitch(app.qPCRAnalyzerUIFigure, 'slider');
            app.GroupBySwitch.Items = {'Sample', 'Target'};
            app.GroupBySwitch.ValueChangedFcn = createCallbackFcn(app, @GroupBySwitchValueChanged, true);
            app.GroupBySwitch.Value = 'Sample';
            app.ExportFiguresButton = uibutton(app.qPCRAnalyzerUIFigure, 'push');
            app.ExportFiguresButton.Text = 'Export Figures';
            app.IncludeNormalizerCheckBox = uicheckbox(app.qPCRAnalyzerUIFigure);
            app.IncludeNormalizerCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeNormalizerCheckBoxValueChanged, true);
            app.IncludeNormalizerCheckBox.Text = 'Include Normalizer';
            % Show the figure after all components are created
            app.qPCRAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qPCR_Analyzer_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.qPCRAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.qPCRAnalyzerUIFigure)
        end
    end
end